classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        AnalyzerButton          matlab.ui.control.StateButton
        GeneratorButton         matlab.ui.control.StateButton
        Lamp                    matlab.ui.control.Lamp
        TurnofftheMarkerButton  matlab.ui.control.Button
        SetamarkeronPeakButton  matlab.ui.control.Button
        GenoutputONOFFButton    matlab.ui.control.StateButton
        SpanEditField           matlab.ui.control.NumericEditField
        SpanEditFieldLabel      matlab.ui.control.Label
        CenterFreqEditField     matlab.ui.control.NumericEditField
        CenterFreqLabel         matlab.ui.control.Label
        BWEditField             matlab.ui.control.NumericEditField
        BWEditFieldLabel        matlab.ui.control.Label
        PowerEditField          matlab.ui.control.NumericEditField
        PowerEditFieldLabel     matlab.ui.control.Label
        FreqEditField           matlab.ui.control.NumericEditField
        FreqEditFieldLabel      matlab.ui.control.Label
        ArcusMotorButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        obj1;
        obj;
        interface = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GenoutputONOFFButton
        function GenoutputONOFFButtonValueChanged(app, event)
            value = app.GenoutputONOFFButton.Value;
            if (value==1)
                app.obj1.on();
                app.Lamp.Color='green';
            else
                app.obj1.off();
                app.Lamp.Color='red';
            end
            
        end

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.close();
                app.Lamp_2.Color='red';
            end
                        
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.close();
                app.Lamp_3.Color='red';
            end
            
        end

        % Button pushed function: SetamarkeronPeakButton
        function SetamarkeronPeakButtonPushed(app, event)
            app.obj.smp(1)
        end

        % Button pushed function: TurnofftheMarkerButton
        function TurnofftheMarkerButtonPushed(app, event)
            app.obj.moff(1) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArcusMotorButton = uibutton(app.UIFigure, 'push');
            app.ArcusMotorButton.Tag = 'Push Button';
            app.ArcusMotorButton.Text = 'Arcus Motor';
            app.FreqEditFieldLabel = uilabel(app.UIFigure);
            app.FreqEditFieldLabel.Text = 'Freq.';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqEditField.Tag = 'Numeric';
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditField.Tag = 'Numeric';
            app.BWEditFieldLabel = uilabel(app.UIFigure);
            app.BWEditFieldLabel.Text = 'BW';
            app.BWEditField = uieditfield(app.UIFigure, 'numeric');
            app.BWEditField.Tag = 'Numeric';
            app.CenterFreqLabel = uilabel(app.UIFigure);
            app.CenterFreqLabel.Text = 'Center Freq.';
            app.CenterFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterFreqEditField.Tag = 'Numeric';
            app.SpanEditFieldLabel = uilabel(app.UIFigure);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpanEditField.Tag = 'Numeric';
            app.GenoutputONOFFButton = uibutton(app.UIFigure, 'state');
            app.GenoutputONOFFButton.ValueChangedFcn = createCallbackFcn(app, @GenoutputONOFFButtonValueChanged, true);
            app.GenoutputONOFFButton.Tag = 'state button on and off';
            app.GenoutputONOFFButton.Text = 'Gen. output ON/OFF';
            app.SetamarkeronPeakButton = uibutton(app.UIFigure, 'push');
            app.SetamarkeronPeakButton.ButtonPushedFcn = createCallbackFcn(app, @SetamarkeronPeakButtonPushed, true);
            app.SetamarkeronPeakButton.Text = 'Set a marker on Peak';
            app.TurnofftheMarkerButton = uibutton(app.UIFigure, 'push');
            app.TurnofftheMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @TurnofftheMarkerButtonPushed, true);
            app.TurnofftheMarkerButton.Text = 'Turn off the Marker';
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end